classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        confirmButton_13         matlab.ui.control.Button
        confirmButton_20         matlab.ui.control.Button
        confirmButton_21         matlab.ui.control.Button
        confirmButton_12         matlab.ui.control.Button
        confirmButton_19         matlab.ui.control.Button
        confirmButton_22         matlab.ui.control.Button
        confirmButton_11         matlab.ui.control.Button
        confirmButton_18         matlab.ui.control.Button
        confirmButton_23         matlab.ui.control.Button
        confirmButton_10         matlab.ui.control.Button
        confirmButton_17         matlab.ui.control.Button
        confirmButton_24         matlab.ui.control.Button
        confirmButton_9          matlab.ui.control.Button
        confirmButton_14         matlab.ui.control.Button
        confirmButton_25         matlab.ui.control.Button
        confirmButton_8          matlab.ui.control.Button
        confirmButton_15         matlab.ui.control.Button
        confirmButton_26         matlab.ui.control.Button
        confirmButton_7          matlab.ui.control.Button
        confirmButton_16         matlab.ui.control.Button
        confirmButton_27         matlab.ui.control.Button
        confirmButton_6          matlab.ui.control.Button
        confirmButton_5          matlab.ui.control.Button
        confirmButton_4          matlab.ui.control.Button
        confirmButton_3          matlab.ui.control.Button
        confirmButton_2          matlab.ui.control.Button
        confirmButton            matlab.ui.control.Button
        Tank3Label               matlab.ui.control.Label
        Tank2Label               matlab.ui.control.Label
        Tank1Label               matlab.ui.control.Label
        Label_9                  matlab.ui.control.Label
        Label_8                  matlab.ui.control.Label
        Label_7                  matlab.ui.control.Label
        Label_6                  matlab.ui.control.Label
        Label_5                  matlab.ui.control.Label
        Label_4                  matlab.ui.control.Label
        Label_3                  matlab.ui.control.Label
        Label_2                  matlab.ui.control.Label
        Label                    matlab.ui.control.Label
        StopButton               matlab.ui.control.Button
        SaveVisualizationButton  matlab.ui.control.Button
        StartButton              matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function recordPoint(app, tank, level, button)

            mdl = "Copy_2_of_calibration_sensors";
        
            blockName = sprintf("confirmlevel%d_%d", level, tank);
            blockPath = mdl + "/" + blockName;
        
            simStatus = get_param(mdl, "SimulationStatus");
        
            if ~strcmp(simStatus, "running")
                uialert(app.UIFigure, ...
                    "Please start Sensor Calibration first.", ...
                    "Simulation not running");
                return
            end
        
            set_param(blockPath, "Value", "1");
            pause(0.2);
            set_param(blockPath, "Value", "0");
        
            button.Text = "Recorded";
            button.Enable = "off";
        
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
                % mdl = "Copy_of_calibration_sensors";
                % 
                % load_system(mdl);
                % 
                % set_param(mdl, ...
                %     "SimulationCommand", ...
                %     "start");


                mdl = "Copy_2_of_calibration_sensors";
            
                try
                    load_system(mdl);
            
                    % 校准实验持续运行，直到你手动 Stop
                    set_param(mdl, "StopTime", "inf");
            
                    % 启动仿真
                    set_param(mdl, ...
                        "SimulationCommand", ...
                        "start");
            
                    pause(0.3);
            
                    status = get_param(mdl, "SimulationStatus");
            
                    if strcmp(status, "running")
            
                        app.StartButton.Text = "Running";
                        app.StartButton.Enable = "off";
            
                    else
            
                        app.StartButton.Text = "Start";
            
                        uialert(app.UIFigure, ...
                            "Model status: " + string(status), ...
                            "Simulation Error");
                    end
            
                catch ME
            
                    app.StartButton.Text = "Start";
            
                    uialert(app.UIFigure, ...
                        ME.message, ...
                        "Start Error");
            
                end

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
                mdl = "Copy_2_of_calibration_sensors";

                set_param(mdl, ...
                    "SimulationCommand", ...
                    "stop");
        end

        % Button pushed function: confirmButton
        function confirmButtonPushed(app, event)
            app.recordPoint(1, 1, app.confirmButton);
        end

        % Button pushed function: confirmButton_2
        function confirmButton_2Pushed(app, event)
            app.recordPoint(2, 1, app.confirmButton_2);
        end

        % Button pushed function: confirmButton_3
        function confirmButton_3Pushed(app, event)
            app.recordPoint(3, 1, app.confirmButton_3);
        end

        % Button pushed function: confirmButton_4
        function confirmButton_4Pushed(app, event)
            app.recordPoint(1, 2, app.confirmButton_4);
        end

        % Button pushed function: confirmButton_5
        function confirmButton_5Pushed(app, event)
            app.recordPoint(2, 2, app.confirmButton_5);
        end

        % Button pushed function: confirmButton_6
        function confirmButton_6Pushed(app, event)
            app.recordPoint(3, 2, app.confirmButton_6);
        end

        % Button pushed function: confirmButton_27
        function confirmButton_27Pushed(app, event)
            app.recordPoint(1, 3, app.confirmButton_27);
        end

        % Button pushed function: confirmButton_16
        function confirmButton_16Pushed(app, event)
            app.recordPoint(2, 3, app.confirmButton_16);
        end

        % Button pushed function: confirmButton_7
        function confirmButton_7Pushed(app, event)
            app.recordPoint(3, 3, app.confirmButton_7);
        end

        % Button pushed function: confirmButton_26
        function confirmButton_26Pushed(app, event)
            app.recordPoint(1, 4, app.confirmButton_26);
        end

        % Button pushed function: confirmButton_15
        function confirmButton_15Pushed(app, event)
            app.recordPoint(2, 4, app.confirmButton_15);
        end

        % Button pushed function: confirmButton_8
        function confirmButton_8Pushed(app, event)
            app.recordPoint(3, 4, app.confirmButton_8);
        end

        % Button pushed function: confirmButton_25
        function confirmButton_25Pushed(app, event)
            app.recordPoint(1, 5, app.confirmButton_25);
        end

        % Button pushed function: confirmButton_14
        function confirmButton_14Pushed(app, event)
            app.recordPoint(2, 5, app.confirmButton_14);
        end

        % Button pushed function: confirmButton_9
        function confirmButton_9Pushed(app, event)
            app.recordPoint(3, 5, app.confirmButton_9);
        end

        % Button pushed function: confirmButton_24
        function confirmButton_24Pushed(app, event)
            app.recordPoint(1, 6, app.confirmButton_24);
        end

        % Button pushed function: confirmButton_17
        function confirmButton_17Pushed(app, event)
            app.recordPoint(2, 6, app.confirmButton_17);
        end

        % Button pushed function: confirmButton_10
        function confirmButton_10Pushed(app, event)
            app.recordPoint(3, 6, app.confirmButton_10);
        end

        % Button pushed function: confirmButton_23
        function confirmButton_23Pushed(app, event)
            app.recordPoint(1, 7, app.confirmButton_23);
        end

        % Button pushed function: confirmButton_18
        function confirmButton_18Pushed(app, event)
            app.recordPoint(2, 7, app.confirmButton_18);
        end

        % Button pushed function: confirmButton_11
        function confirmButton_11Pushed(app, event)
            app.recordPoint(3, 7, app.confirmButton_11);
        end

        % Button pushed function: confirmButton_22
        function confirmButton_22Pushed(app, event)
            app.recordPoint(1, 8, app.confirmButton_22);
        end

        % Button pushed function: confirmButton_19
        function confirmButton_19Pushed(app, event)
            app.recordPoint(2, 8, app.confirmButton_19);
        end

        % Button pushed function: confirmButton_12
        function confirmButton_12Pushed(app, event)
            app.recordPoint(3, 8, app.confirmButton_12);
        end

        % Button pushed function: confirmButton_21
        function confirmButton_21Pushed(app, event)
            app.recordPoint(1, 9, app.confirmButton_21);
        end

        % Button pushed function: confirmButton_20
        function confirmButton_20Pushed(app, event)
            app.recordPoint(2, 9, app.confirmButton_20);
        end

        % Button pushed function: confirmButton_13
        function confirmButton_13Pushed(app, event)
            app.recordPoint(3, 9, app.confirmButton_13);
        end

        % Button pushed function: SaveVisualizationButton
        function SaveVisualizationButtonPushed(app, event)

            % 从 workspace 获取系数
            coef = evalin("base", "coefficients");

            % 保存系数
            save("sensorCalibration.mat", "coef");

            % 电压范围 0~3.3 V
            V = linspace(0, 3.3, 500);

            % 三个 tank 的拟合曲线
            h1 = polyval(coef(1,:), V);
            h2 = polyval(coef(2,:), V);
            h3 = polyval(coef(3,:), V);

            % 清空旧图
            cla(app.UIAxes);

            % Tank 1
            plot(app.UIAxes, V, h1, ...
                "LineWidth", 1.5, ...
                "DisplayName", "Tank 1");

            hold(app.UIAxes, "on");

            % Tank 2
            plot(app.UIAxes, V, h2, ...
                "LineWidth", 1.5, ...
                "DisplayName", "Tank 2");

            % Tank 3
            plot(app.UIAxes, V, h3, ...
                "LineWidth", 1.5, ...
                "DisplayName", "Tank 3");

            hold(app.UIAxes, "off");

            % 图形设置
            xlabel(app.UIAxes, "Sensor Voltage (V)");
            ylabel(app.UIAxes, "Water Height (cm)");
            title(app.UIAxes, "Sensor Calibration Curves");

            xlim(app.UIAxes, [0 3.3]);

            grid(app.UIAxes, "on");

            legend(app.UIAxes, ...
                "show", ...
                "Location", "best");

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SaveVisualizationButton = uibutton(app.UIFigure, 'push');
            app.SaveVisualizationButton.ButtonPushedFcn = createCallbackFcn(app, @SaveVisualizationButtonPushed, true);
            app.SaveVisualizationButton.Text = 'Save & Visualization';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '10';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '15';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '20';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '25';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '30';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '35';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '40';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '45';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '50';
            app.Tank1Label = uilabel(app.UIFigure);
            app.Tank1Label.Text = 'Tank1';
            app.Tank2Label = uilabel(app.UIFigure);
            app.Tank2Label.Text = 'Tank2';
            app.Tank3Label = uilabel(app.UIFigure);
            app.Tank3Label.Text = 'Tank3';
            app.confirmButton = uibutton(app.UIFigure, 'push');
            app.confirmButton.ButtonPushedFcn = createCallbackFcn(app, @confirmButtonPushed, true);
            app.confirmButton.Text = 'confirm';
            app.confirmButton_2 = uibutton(app.UIFigure, 'push');
            app.confirmButton_2.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_2Pushed, true);
            app.confirmButton_2.Text = 'confirm';
            app.confirmButton_3 = uibutton(app.UIFigure, 'push');
            app.confirmButton_3.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_3Pushed, true);
            app.confirmButton_3.Text = 'confirm';
            app.confirmButton_4 = uibutton(app.UIFigure, 'push');
            app.confirmButton_4.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_4Pushed, true);
            app.confirmButton_4.Text = 'confirm';
            app.confirmButton_5 = uibutton(app.UIFigure, 'push');
            app.confirmButton_5.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_5Pushed, true);
            app.confirmButton_5.Text = 'confirm';
            app.confirmButton_6 = uibutton(app.UIFigure, 'push');
            app.confirmButton_6.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_6Pushed, true);
            app.confirmButton_6.Text = 'confirm';
            app.confirmButton_27 = uibutton(app.UIFigure, 'push');
            app.confirmButton_27.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_27Pushed, true);
            app.confirmButton_27.Text = 'confirm';
            app.confirmButton_16 = uibutton(app.UIFigure, 'push');
            app.confirmButton_16.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_16Pushed, true);
            app.confirmButton_16.Text = 'confirm';
            app.confirmButton_7 = uibutton(app.UIFigure, 'push');
            app.confirmButton_7.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_7Pushed, true);
            app.confirmButton_7.Text = 'confirm';
            app.confirmButton_26 = uibutton(app.UIFigure, 'push');
            app.confirmButton_26.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_26Pushed, true);
            app.confirmButton_26.Text = 'confirm';
            app.confirmButton_15 = uibutton(app.UIFigure, 'push');
            app.confirmButton_15.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_15Pushed, true);
            app.confirmButton_15.Text = 'confirm';
            app.confirmButton_8 = uibutton(app.UIFigure, 'push');
            app.confirmButton_8.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_8Pushed, true);
            app.confirmButton_8.Text = 'confirm';
            app.confirmButton_25 = uibutton(app.UIFigure, 'push');
            app.confirmButton_25.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_25Pushed, true);
            app.confirmButton_25.Text = 'confirm';
            app.confirmButton_14 = uibutton(app.UIFigure, 'push');
            app.confirmButton_14.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_14Pushed, true);
            app.confirmButton_14.Text = 'confirm';
            app.confirmButton_9 = uibutton(app.UIFigure, 'push');
            app.confirmButton_9.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_9Pushed, true);
            app.confirmButton_9.Text = 'confirm';
            app.confirmButton_24 = uibutton(app.UIFigure, 'push');
            app.confirmButton_24.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_24Pushed, true);
            app.confirmButton_24.Text = 'confirm';
            app.confirmButton_17 = uibutton(app.UIFigure, 'push');
            app.confirmButton_17.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_17Pushed, true);
            app.confirmButton_17.Text = 'confirm';
            app.confirmButton_10 = uibutton(app.UIFigure, 'push');
            app.confirmButton_10.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_10Pushed, true);
            app.confirmButton_10.Text = 'confirm';
            app.confirmButton_23 = uibutton(app.UIFigure, 'push');
            app.confirmButton_23.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_23Pushed, true);
            app.confirmButton_23.Text = 'confirm';
            app.confirmButton_18 = uibutton(app.UIFigure, 'push');
            app.confirmButton_18.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_18Pushed, true);
            app.confirmButton_18.Text = 'confirm';
            app.confirmButton_11 = uibutton(app.UIFigure, 'push');
            app.confirmButton_11.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_11Pushed, true);
            app.confirmButton_11.Text = 'confirm';
            app.confirmButton_22 = uibutton(app.UIFigure, 'push');
            app.confirmButton_22.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_22Pushed, true);
            app.confirmButton_22.Text = 'confirm';
            app.confirmButton_19 = uibutton(app.UIFigure, 'push');
            app.confirmButton_19.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_19Pushed, true);
            app.confirmButton_19.Text = 'confirm';
            app.confirmButton_12 = uibutton(app.UIFigure, 'push');
            app.confirmButton_12.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_12Pushed, true);
            app.confirmButton_12.Text = 'confirm';
            app.confirmButton_21 = uibutton(app.UIFigure, 'push');
            app.confirmButton_21.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_21Pushed, true);
            app.confirmButton_21.Text = 'confirm';
            app.confirmButton_20 = uibutton(app.UIFigure, 'push');
            app.confirmButton_20.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_20Pushed, true);
            app.confirmButton_20.Text = 'confirm';
            app.confirmButton_13 = uibutton(app.UIFigure, 'push');
            app.confirmButton_13.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_13Pushed, true);
            app.confirmButton_13.Text = 'confirm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end